classdef appev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        CityDropDown                    matlab.ui.control.DropDown
        CityDropDownLabel               matlab.ui.control.Label
        VehicleModelListDropDown_2      matlab.ui.control.DropDown
        VehicleModelListDropDown_2Label  matlab.ui.control.Label
        VehicleModelListDropDown        matlab.ui.control.DropDown
        VehicleModelListLabel           matlab.ui.control.Label
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        DCButton                        matlab.ui.control.RadioButton
        ACButton                        matlab.ui.control.RadioButton
        StationPowerEditField           matlab.ui.control.NumericEditField
        StationPowerEditFieldLabel      matlab.ui.control.Label
        VIPEditField_2                  matlab.ui.control.NumericEditField
        VIPEditField_2Label             matlab.ui.control.Label
        BatteryCapacityEditField_2      matlab.ui.control.NumericEditField
        BatteryCapacityLabel            matlab.ui.control.Label
        SOCEditField_2Label             matlab.ui.control.Label
        SOCEditField_2                  matlab.ui.control.NumericEditField
        VIPEditField                    matlab.ui.control.NumericEditField
        VIPEditFieldLabel               matlab.ui.control.Label
        BatteryCapacityEditField        matlab.ui.control.NumericEditField
        BatteryCapacityLabel_2          matlab.ui.control.Label
        SOCEditFieldLabel               matlab.ui.control.Label
        SOCEditField                    matlab.ui.control.NumericEditField
        START                           matlab.ui.control.Button
        Vehicle2Label                   matlab.ui.control.Label
        Vehicle1Label                   matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Tab2                            matlab.ui.container.Tab
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalTimeEditFieldLabel         matlab.ui.control.Label
        ACStationPowerEditField         matlab.ui.control.NumericEditField
        ACStationPowerEditFieldLabel    matlab.ui.control.Label
        DCStationPowerEditField         matlab.ui.control.NumericEditField
        DCStationPowerLabel             matlab.ui.control.Label
        GenerateTableButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        MaxChargingPowerEditField       matlab.ui.control.NumericEditField
        MaxChargingPowerEditFieldLabel  matlab.ui.control.Label
        BatteryCapacityEditField_3      matlab.ui.control.NumericEditField
        BatteryCapacityEditField_3Label  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        NumberofVehiclesEditField       matlab.ui.control.NumericEditField
        NumberofVehiclesEditFieldLabel  matlab.ui.control.Label
        NumberofACStationEditField      matlab.ui.control.NumericEditField
        NumberofACStationEditFieldLabel  matlab.ui.control.Label
        NumberofDCStationEditField      matlab.ui.control.NumericEditField
        NumberofDCStationEditFieldLabel  matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        Option2Tablefor80ofallscenariosLabel  matlab.ui.control.Label
        Option1Tablefor50ofallscenariosLabel  matlab.ui.control.Label
        Option2Table                    matlab.ui.control.Table
        Option1Table                    matlab.ui.control.Table
        StartButton_2                   matlab.ui.control.Button
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        BatteryCapacityEditField_4      matlab.ui.control.NumericEditField
        BatteryCapacityLabel_3          matlab.ui.control.Label
        ClassDropDown                   matlab.ui.control.DropDown
        ClassDropDownLabel              matlab.ui.control.Label
        NumberofVehiclesEditField_2     matlab.ui.control.NumericEditField
        NumberofVehiclesLabel           matlab.ui.control.Label
        HistogramAxes                   matlab.ui.control.UIAxes
        Tab4                            matlab.ui.container.Tab
        Option2Tablefor80ofallscenariosLabel_2  matlab.ui.control.Label
        Option1Tablefor50ofallscenariosLabel_2  matlab.ui.control.Label
        Option2table2                   matlab.ui.control.Table
        Option1table1                   matlab.ui.control.Table
        NumberofDCStationEditField_2    matlab.ui.control.NumericEditField
        NumberofDCStationEditField_2Label  matlab.ui.control.Label
        NumberofACStationEditField_2    matlab.ui.control.NumericEditField
        NumberofACStationEditField_2Label  matlab.ui.control.Label
        BatteryCapacityEditField_5      matlab.ui.control.NumericEditField
        BatteryCapacityEditField_5Label  matlab.ui.control.Label
        TimeEditField_2                 matlab.ui.control.NumericEditField
        TimeEditField_2Label            matlab.ui.control.Label
        DCStationPowerEditField_2       matlab.ui.control.NumericEditField
        DCStationPowerEditField_2Label  matlab.ui.control.Label
        ACStationPowerEditField_2       matlab.ui.control.NumericEditField
        ACStationPowerLabel             matlab.ui.control.Label
        StartButton_3                   matlab.ui.control.Button
        HistogramAxes5                  matlab.ui.control.UIAxes
        HistogramAxes4                  matlab.ui.control.UIAxes
        Tab5                            matlab.ui.container.Tab
        NumberofVehiclesEditField_3     matlab.ui.control.NumericEditField
        NumberofVehiclesLabel_2         matlab.ui.control.Label
        BatteryCapacityEditField_6      matlab.ui.control.NumericEditField
        BatteryCapacityLabel_4          matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        DCStationPowerEditField_3       matlab.ui.control.NumericEditField
        DCStationPowerLabel_2           matlab.ui.control.Label
        GenerateTableButton_2           matlab.ui.control.Button
        StartButton_4                   matlab.ui.control.Button
        ACStationPowerEditField_3       matlab.ui.control.NumericEditField
        ACStationPowerLabel_2           matlab.ui.control.Label
        NumberofDCStationEditField_3    matlab.ui.control.NumericEditField
        NumberofDCStationLabel          matlab.ui.control.Label
        NumberofACStationEditField_3    matlab.ui.control.NumericEditField
        NumberofACStationLabel          matlab.ui.control.Label
        TotalTimeEditField_2            matlab.ui.control.NumericEditField
        TotalTimeLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        StopTime = 3600;
    end
    
    methods (Access = private)
         function temperature = getWeather(app, cityName)
        apiKey = 'INSERT_API_KEY_HERE';
        baseURL = 'http://api.weatherapi.com/v1/current.json';

        % Şehir adını küçük harf ve Türkçe karakter dönüşümü
        cityName = lower(cityName);
        cityName = strrep(cityName, ' ', '%20');
        cityName = strrep(cityName, 'ç', 'c');
        cityName = strrep(cityName, 'ğ', 'g');
        cityName = strrep(cityName, 'ı', 'i');
        cityName = strrep(cityName, 'ö', 'o');
        cityName = strrep(cityName, 'ş', 's');
        cityName = strrep(cityName, 'ü', 'u');

        % URL oluştur
        url = sprintf('%s?key=%s&q=%s', baseURL, apiKey, cityName);

        try
            data = webread(url);  % JSON veriyi al
            temperature = data.current.temp_c;  % Sıcaklık °C
        catch
            temperature = NaN;
        end
    end


        function totalTime = evaluateScenario(app,scenario, vehicleData, capacity, sim_time, stationPower)
           disp('EVALUATE SCENARIO ÇALIŞTI ✅');

% Eğer tek araç varsa:
if size(scenario,1) == 1
    idx = scenario(1,1);
    soc = vehicleData(idx,1);
    maxGuc = vehicleData(idx,3);

    realPower = min(maxGuc, stationPower);
    totalEnergyNeeded = (1 - soc) * capacity * 3600; % joule
    totalTime = totalEnergyNeeded / realPower;       % saniye
    totalTime = min(totalTime, sim_time);
    return
end

% İki araç varsa:
idx1 = scenario(1,1);
idx2 = scenario(2,1);

soc1 = vehicleData(idx1, 1);
soc2 = vehicleData(idx2, 1);
vip1 = vehicleData(idx1, 2);
vip2 = vehicleData(idx2, 2);
maxGuc1 = vehicleData(idx1, 3);
maxGuc2 = vehicleData(idx2, 3);

start1 = -1;
start2 = -1;
finish1 = -1;
finish2 = -1;

dt = 1;
for t = 1:sim_time
    isConnected1 = soc1 < 1;
    isConnected2 = soc2 < 1;

    % SOC kontrolü yapılmalı
    if isConnected1 && start1 == -1
        start1 = t;
    end
    if isConnected2 && start2 == -1
        start2 = t;
    end

    % Güç paylaşımı
    if isConnected1 && isConnected2
        if vip1 > vip2
            P1 = 0.7 * stationPower;
            P2 = 0.3 * stationPower;
        elseif vip2 > vip1
            P1 = 0.3 * stationPower;
            P2 = 0.7 * stationPower;
        else
            P1 = 0.5 * stationPower;
            P2 = 0.5 * stationPower;
        end
    elseif isConnected1
        P1 = stationPower;
        P2 = 0;
    elseif isConnected2
        P1 = 0;
        P2 = stationPower;
    else
    if soc1 >= 1 && finish1 == -1
        finish1 = t;
    end
    if soc2 >= 1 && finish2 == -1
        finish2 = t;
    end
    break;
end
    % Gerçek güç ve aktarım
    realGuc1 = min(P1, maxGuc1);
    realGuc2 = min(P2, maxGuc2);

    if P1 > maxGuc1 && isConnected2
        aktar = min(P1 - maxGuc1, maxGuc2 - realGuc2);
        realGuc2 = realGuc2 + aktar;
    end
    if P2 > maxGuc2 && isConnected1
        aktar = min(P2 - maxGuc2, maxGuc1 - realGuc1);
        realGuc1 = realGuc1 + aktar;
    end

    % SOC artışı
    if isConnected1
        soc1 = soc1 + (realGuc1 / (capacity * 3600));
        soc1 = min(soc1, 1);
        if soc1 >= 1 && finish1 == -1
            finish1 = t;
        end
    end
    if isConnected2
        soc2 = soc2 + (realGuc2 / (capacity * 3600));
        soc2 = min(soc2, 1);
        if soc2 >= 1 && finish2 == -1
            finish2 = t;
        end
    end
end

% Eğer bitiş süresi belirlenmemişse sim_time ver
if finish1 == -1
    finish1 = sim_time;
end
if finish2 == -1
    finish2 = sim_time;
end

% Simülasyonun en uzun süresi
totalTime = max(finish1, finish2);
        end
        
        function vehicleResults = simulateAllVehicles(app, finalAssignments, vehicleData, capacity, sim_time, acPower, dcPower)
            disp('Simülasyon BAŞLADI 🚀');
    
    numVehicles = size(vehicleData,1);
    vehicleResults = cell(numVehicles, 7);  % Araç No, Tip, İstasyon, Başlama, Süre, Bitiş, Bağlı mı?

    uniqueStations = unique(finalAssignments(:,2:3), 'rows');  % [Tip, Istasyon]

    for i = 1:size(uniqueStations,1)
        tip = uniqueStations(i,1);
        stationID = uniqueStations(i,2);

        % O istasyona bağlı araçları seç
        stationMask = finalAssignments(:,2) == tip & finalAssignments(:,3) == stationID;
        group = finalAssignments(stationMask, :);

        % Güç belirleme
        if tip == 1
            stationPower = acPower;
        else
            stationPower = dcPower;
        end

        % evaluateScenario çağrılır
        chargingTime = evaluateScenario(app, group, vehicleData, capacity, sim_time, stationPower);

        for k = 1:size(group,1)
            v = group(k,1);  % Araç ID

            vehicleResults{v,1} = v;
            vehicleResults{v,2} = tip;
            vehicleResults{v,3} = stationID;
            vehicleResults{v,4} = 0;  % Başlangıç zamanı (şimdilik 0)
            vehicleResults{v,5} = chargingTime;  % Süre
            vehicleResults{v,6} = chargingTime;  % Bitiş zamanı
            vehicleResults{v,7} = true;  % Aktif bağlandı
        end
    end

    disp('Simülasyon TAMAMLANDI ✅');
    disp('--- DEBUG: vehicleResults columns ---');
for i = 1:size(vehicleResults,2)
    sample = vehicleResults{1,i};
    fprintf('Column %d: Example value = %s\n', i, mat2str(sample));
end
        end
        function [vehicleResults] = simulateTwoVehicles(vID1, vID2, vehicleResults, dcPower, capacity, sim_time)
         dt = 1;
    time = 0:dt:sim_time;

    soc1 = vehicleResults(vID1).soc;
    soc2 = vehicleResults(vID2).soc;

    start1 = -1; finish1 = -1;
    start2 = -1; finish2 = -1;

    for t = time
        if soc1 < 1
            if start1 == -1
                start1 = t;
            end
            power1 = dcPower / 2;
            deltaSOC = power1 / (3600 * capacity);
            soc1 = min(soc1 + deltaSOC, 1);
            if soc1 >= 1
                finish1 = t;
            end
        end

        if soc2 < 1
            if start2 == -1
                start2 = t;
            end
            power2 = dcPower / 2;
            deltaSOC = power2 / (3600 * capacity);
            soc2 = min(soc2 + deltaSOC, 1);
            if soc2 >= 1
                finish2 = t;
            end
        end

        if soc1 >= 1 && soc2 >= 1
            break;
        end
    end

    vehicleResults(vID1).startTime = start1;
    vehicleResults(vID1).finishTime = finish1;
    vehicleResults(vID1).chargeTime = finish1 - start1;
    vehicleResults(vID1).soc = soc1;

    vehicleResults(vID2).startTime = start2;
    vehicleResults(vID2).finishTime = finish2;
    vehicleResults(vID2).chargeTime = finish2 - start2;
    vehicleResults(vID2).soc = soc2;
            
        end
        
        function [vehicleResults] = simulateSingleVehicle(vID, vehicleResults, dcPower, capacity, maxPower, sim_time)
           dt = 1;
    time = 0:dt:sim_time;

    soc = vehicleResults(vID).soc;
    start = -1; finish = -1;

    for t = time
        if soc < 1
            if start == -1
                start = t;
            end
            power = min(dcPower, maxPower);
            deltaSOC = power / (3600 * capacity);
            soc = min(soc + deltaSOC, 1);
            if soc >= 1
                finish = t;
                break;
            end
        end
    end

    vehicleResults(vID).startTime = start;
    vehicleResults(vID).finishTime = finish;
    vehicleResults(vID).chargeTime = finish - start;
    vehicleResults(vID).soc = soc; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: START
        function STARTButtonPushed(app, event)
% Kullanıcı girişleri
soc1 = app.SOCEditField.Value;
max1 = app.BatteryCapacityEditField.Value;
vip1 = app.VIPEditField.Value;


soc2 = app.SOCEditField_2.Value;
max2 = app.BatteryCapacityEditField_2.Value;
vip2 = app.VIPEditField_2.Value;


P_total = app.StationPowerEditField.Value;

sim_time = app.StopTime;                      % 3 saatlik simülasyon
dt = 1;                                     % Zaman adımı 1 saniye
time = 0:dt:sim_time;

% Geçmişleri tutacak vektörler
soc_hist1 = zeros(size(time));
soc_hist2 = zeros(size(time));

% Simülasyon döngüsü
for t = 1:length(time)

    % Araç hala bağlı mı? (%100 dolunca bağlı değil say)
    isConnected1 = soc1 < 1;
    isConnected2 = soc2 < 1;
   criticalSOC1 = soc1 < 0.05;
    criticalSOC2 = soc2 < 0.05;
    % Dağılım mantığı
    if isConnected1 && isConnected2
       if criticalSOC1 && ~criticalSOC2
            P1 = 0.7 * P_total;
            P2 = 0.3 * P_total;
        elseif criticalSOC2 && ~criticalSOC1
            P1 = 0.3 * P_total;
            P2 = 0.7 * P_total;
        else
            % İki araç da kritik SOC'ta değilse → VIP karşılaştır
            if vip1 > vip2
                P1 = 0.7 * P_total;
                P2 = 0.3 * P_total;
            elseif vip2 > vip1
                P1 = 0.3 * P_total;
                P2 = 0.7 * P_total;
            else
                P1 = 0.5 * P_total;
                P2 = 0.5 * P_total;
            end
        end
    elseif isConnected1
        P1 = P_total;
        P2 = 0;
    elseif isConnected2
        P1 = 0;
        P2 = P_total;
    else
        P1 = 0;
        P2 = 0;
    end
    realGuc1 = P1;
    realGuc2 = P2;


    % --- SOC artışı (gerçek güçle)
    if isConnected1
        soc1 = soc1 + (realGuc1 / (3600 * max1));
        soc1 = min(soc1, 1);
    end
    if isConnected2
        soc2 = soc2 + (realGuc2 / (3600 * max2));
        soc2 = min(soc2, 1);
    end
   

    % Kayıt
    soc_hist1(t) = soc1;
    soc_hist2(t) = soc2;
end

% Grafik çizimi
plot(app.UIAxes, time / 3600, soc_hist1, 'r', 'LineWidth', 1.5);
title(app.UIAxes, 'Vehicle 1 SOC');
xlabel(app.UIAxes, 'Time (hour)');
ylabel(app.UIAxes, 'SOC');

plot(app.UIAxes2, time / 3600, soc_hist2, 'b', 'LineWidth', 1.5);
title(app.UIAxes2, 'Vehicle 2 SOC');
xlabel(app.UIAxes2, 'Time (hour)');
ylabel(app.UIAxes2, 'SOC');

plot(app.UIAxes3, time / 3600, soc_hist1, 'r', 'LineWidth', 1.5); hold(app.UIAxes3, 'on');
plot(app.UIAxes3, time / 3600, soc_hist2, 'b', 'LineWidth', 1.5); hold(app.UIAxes3, 'off');
title(app.UIAxes3, 'Vehicle 1 ve Vehicle 2 SOC Comparison');
xlabel(app.UIAxes3, 'Time (hour)');
ylabel(app.UIAxes3, 'SOC');
legend(app.UIAxes3, {'Vehicle 1', 'Vehicle 2'});
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            selection = selectedButton.Text;

    if strcmp(selection, 'DC')
        app.StopTime = 3600;  % 1 hour in seconds
        disp('DC mode selected → 1 hour (3600 seconds)');

    elseif strcmp(selection, 'AC')
        app.StopTime = 14400;  % 4 hours in seconds
        disp('AC mode selected → 4 hours (14400 seconds)');
    end
        end

        % Callback function: Tab, VehicleModelListDropDown, 
        % VehicleModelListDropDown
        function VehicleModelListDropDownValueChanged(app, event)
            
            selected = char(app.VehicleModelListDropDown.Value);

switch selected
    case 'Tesla Model 3'
        app.BatteryCapacityEditField.Value = 75;
        
        app.BatteryCapacityEditField.Editable = false;
        

    case 'Renault Zoe'
        app.BatteryCapacityEditField.Value = 52;
        
        app.BatteryCapacityEditField.Editable = false;
       

    case 'Hyundai Ioniq 5'
        app.BatteryCapacityEditField.Value = 77.4;
        
        app.BatteryCapacityEditField.Editable = false;
        

    case 'Custom'
        app.BatteryCapacityEditField.Value = 0;
        
        app.BatteryCapacityEditField.Editable = true;
       

end
        end

        % Value changed function: VehicleModelListDropDown_2
        function VehicleModelListDropDown_2ValueChanged(app, event)
           selected = char(app.VehicleModelListDropDown_2.Value);

    switch selected
        case 'Tesla Model 3'
            app.BatteryCapacityEditField_2.Value = 75;
            
            app.BatteryCapacityEditField_2.Editable = false;
           

        case 'Renault Zoe'
            app.BatteryCapacityEditField_2.Value = 52;
            
            app.BatteryCapacityEditField_2.Editable = false;
           

        case 'Hyundai Ioniq 5'
            app.BatteryCapacityEditField_2.Value = 77.4;
           
            app.BatteryCapacityEditField_2.Editable = false;
            

        case 'Custom'
            app.BatteryCapacityEditField_2.Value = 0;
            
            app.BatteryCapacityEditField_2.Editable = true;


    end
        end

        % Button pushed function: GenerateTableButton
        function GenerateTableButtonPushed(app, event)
    numVehicles = app.NumberofVehiclesEditField.Value;

    % Tablo başlıkları
    columnNames = {'Vehicle', 'SOC (%)', 'VIP Level', ...
                   'Assigned Station', 'Start Time (min)', ...
                   'Charging Time (min)', 'Finish Time (min)'};

    % Boş tablo verisi oluştur
    tableData = cell(numVehicles, 7);
    for i = 1:numVehicles
        tableData{i, 1} = sprintf('Vehicle %d', i);  % Araç adı
        tableData{i, 2} = 0;                         % Başlangıç SOC (%)
        tableData{i, 3} = 0;                         % VIP seviyesi
        tableData{i, 4} = '-';                       % Assigned Station (daha sonra atanacak)
        tableData{i, 5} = '-';                       % Start Time
        tableData{i, 6} = '-';                       % Charging Time
        tableData{i, 7} = '-';                       % Finish Time
    end

    % Tabloyu doldur
    app.UITable.Data = tableData;
    app.UITable.ColumnName = columnNames;

    % Düzenlenebilir sütunlar (sadece SOC ve VIP girilebilir)
    app.UITable.ColumnEditable = [false true true false false false false];

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% Kullanıcı girişleri
numVehicles = app.NumberofVehiclesEditField.Value;
capacity    = app.BatteryCapacityEditField_3.Value;      % Ortak kapasite (kWh)
maxPower    = app.MaxChargingPowerEditField.Value;       % Ortak max güç (kW)
acCount     = app.NumberofACStationEditField.Value;
acPower     = app.ACStationPowerEditField.Value;
dcCount     = app.NumberofDCStationEditField.Value;
dcPower     = app.DCStationPowerEditField.Value;
sim_time = 36000;  % 4 saat

% Araç bilgileri
data = app.UITable.Data;
socList = zeros(1, numVehicles);   % Normalized SOC (0-1)
vipList = zeros(1, numVehicles);   % VIP seviyeleri

for i = 1:numVehicles
    socList(i) = data{i, 2} / 100;
    vipList(i) = data{i, 3};
end

priorityScore = zeros(1, numVehicles);
for j = 1:numVehicles
    soc = socList(j);
    vip = vipList(j);

    if vip == 2 && soc < 0.05
        priorityScore(j) = 1;
    elseif soc < 0.05
        priorityScore(j) = 2;
    elseif vip == 2
        priorityScore(j) = 3;
    elseif vip == 1 && soc < 0.05
        priorityScore(j) = 4;
    elseif vip == 1
        priorityScore(j) = 5;
    else
        priorityScore(j) = 6;
    end
end

% Önce öncelik, sonra düşük SOC değerine göre sıralama
priorityScoreList = priorityScore(:);
socListNeg = -socList(:);  % SOC küçük olan öne gelsin diye negatif alındı
combinedTable = [priorityScoreList, socListNeg];
[~, sortedIdx] = sortrows(combinedTable);  % Önce priority, sonra düşük SOC
sortedVehicleIdx = sortedIdx(:)';
% Soket listesi: [tip (1=AC, 2=DC), istasyon, soketNo]
socketList = [];
for i = 1:acCount
    socketList = [socketList; 1, i, 1; 1, i, 2];
end
for i = 1:dcCount
    socketList = [socketList; 2, i, 1; 2, i, 2];
end
assignedToDC = [];
dcPrimarySockets = [];

if dcCount > 0
    % Her DC istasyonunun ilk soketini hazırla
    dcStations = unique(socketList(socketList(:,1) == 2, 2));  % DC istasyon numaraları
    for i = 1:length(dcStations)
        dcPrimarySockets = [dcPrimarySockets; 2, dcStations(i), 1];
    end

    % DC'ye atanacak maksimum araç sayısı
    numDC = min(size(dcPrimarySockets,1), length(sortedVehicleIdx));

    % Öncelik sırasına göre araçları al
    assignedToDC = sortedVehicleIdx(1:numDC);

    % Eğer atanamayan varsa, SOC'e göre tamamla
    if length(assignedToDC) < numDC
        remaining = setdiff(1:numVehicles, assignedToDC);
        [~, idxSOC] = sort(socList(remaining));
        extra = remaining(idxSOC(1:(numDC - length(assignedToDC))));
        assignedToDC = [assignedToDC, extra];
    end

    % DC ataması: [Araç#, Tip=2, İstasyon#, Soket#]
    dcAssignments = [assignedToDC(:), dcPrimarySockets(1:length(assignedToDC), :)];

else
    dcAssignments = [];  % DC istasyonu yoksa boş
end
assignedToAC = [];
acSockets = socketList(socketList(:,1) == 1, :);

% DC'ye atanmış olanları çıkar
remainingVehicles = setdiff(1:numVehicles, assignedToDC);

if acCount > 0 && ~isempty(remainingVehicles)

    if dcCount > 0
        % DC varsa: Önemsiz araçlardan SOC yüksek olanları ata
        remScores = priorityScore(remainingVehicles);
        maxPriority = max(remScores);
        lowPriorityVehicles = remainingVehicles(remScores == maxPriority);
        [~, idxSOC] = max(socList(lowPriorityVehicles));
        acVehicle = lowPriorityVehicles(idxSOC);
    else
        % DC yoksa: En öncelikli ve SOC düşük olanı AC’ye ata
        remScores = priorityScore(remainingVehicles);
        socSubset = socList(remainingVehicles);
        [~, localIdx] = sortrows([remScores(:), socSubset(:)]);
        acVehicle = remainingVehicles(localIdx(1));
    end

    % İlk AC soketi al
    acSocket = acSockets(1, :);
    assignedToAC = acVehicle;
    acAssignment = [acVehicle, acSocket];

else
    acAssignment = [];  % AC yoksa veya atama yapılamıyorsa
end
% 1. Kullanılmış soketleri çıkar
usedSockets = [];

if exist('dcAssignments', 'var') && ~isempty(dcAssignments)
    usedSockets = [usedSockets; dcAssignments(:,2:4)];
end

if exist('acAssignments', 'var') && ~isempty(acAssignments)
    usedSockets = [usedSockets; acAssignments(:,2:4)];
end
% --- Atanmış araçları topla ---
allAssigned = [];

if exist('dcAssignments', 'var') && ~isempty(dcAssignments)
    allAssigned = [allAssigned; dcAssignments(:,1)];
end

if exist('acAssignments', 'var') && ~isempty(acAssignments)
    allAssigned = [allAssigned; acAssignments(:,1)];
end

allAssigned = unique(allAssigned);
remainingVehicles = setdiff(1:numVehicles, allAssigned);

% --- Kullanılan soketleri topla ---
usedSockets = [];

if exist('dcAssignments', 'var') && ~isempty(dcAssignments)
    usedSockets = [usedSockets; dcAssignments(:,2:4)];
end

if exist('acAssignments', 'var') && ~isempty(acAssignments)
    usedSockets = [usedSockets; acAssignments(:,2:4)];
end

% --- Kalan soketleri hesapla ---
remainingSockets = setdiff(socketList, usedSockets, 'rows');
dc2Assignments = [];
ac2Assignments = [];

% --- DC 2. Soket Ataması ---
dcSecondSockets = remainingSockets(remainingSockets(:,1) == 2, :);
if ~isempty(dcSecondSockets) && ~isempty(remainingVehicles)
    remainingScores = priorityScore(remainingVehicles);
    remainingSOCs = socList(remainingVehicles);
    combinedDC = [remainingScores(:), remainingSOCs(:)];
    [~, dcSortIdx] = sortrows(combinedDC);
    dcSortedVehicles = remainingVehicles(dcSortIdx);

    numDC2 = min(size(dcSecondSockets,1), length(dcSortedVehicles));
    dc2Assignments = [dcSortedVehicles(1:numDC2)', dcSecondSockets(1:numDC2, :)];

    allAssigned = [allAssigned; dc2Assignments(:,1)];
    remainingVehicles = setdiff(remainingVehicles, dc2Assignments(:,1));
end

% --- AC 2. Soket Ataması ---
acSecondSockets = remainingSockets(remainingSockets(:,1) == 1, :);
if ~isempty(acSecondSockets) && ~isempty(remainingVehicles)
    remainingScores = priorityScore(remainingVehicles);
    remainingSOCs = socList(remainingVehicles);
    combinedAC = [remainingScores(:), -remainingSOCs(:)];
    [~, acSortIdx] = sortrows(combinedAC);
    acSortedVehicles = remainingVehicles(acSortIdx);

    numAC2 = min(size(acSecondSockets,1), length(acSortedVehicles));
    ac2Assignments = [acSortedVehicles(1:numAC2)', acSecondSockets(1:numAC2, :)];
end

% --- Final Atama Listesi ---
finalAssignments = [];

if exist('dcAssignments', 'var') && ~isempty(dcAssignments)
    finalAssignments = [finalAssignments; dcAssignments];
end

if exist('acAssignments', 'var') && ~isempty(acAssignments)
    finalAssignments = [finalAssignments; acAssignments];
end

if exist('dc2Assignments', 'var') && ~isempty(dc2Assignments)
    finalAssignments = [finalAssignments; dc2Assignments];
end

if exist('ac2Assignments', 'var') && ~isempty(ac2Assignments)
    finalAssignments = [finalAssignments; ac2Assignments];
end

% --- Tüm Araçlar için Simülasyon ---
% Araç bilgilerini oluştur: [SOC, VIP, MaxPower]
vehicleData = zeros(numVehicles, 3);
data = app.UITable.Data;

vehicleData = [socList(:), vipList(:), maxPower * ones(numVehicles,1)];
vehicleResults = simulateAllVehicles(app, finalAssignments, vehicleData, capacity, sim_time, acPower, dcPower);
disp('--- DEBUG: vehicleResults ---');
disp(vehicleResults);
% --- Toplam süreyi belirle (en son biten araç)
finishTimes = cell2mat(vehicleResults(:, 2));   % 2. sütun: FinishTime (saniye cinsinden)
totalTime = max(finishTimes);                   % En geç biten araç
app.TotalTimeEditField.Value = round(totalTime / 60);  % Dakika olarak GUI'ye yaz

% Var olan tabloyu oku (mevcut SOC, VIP vs. bilgileri korunur)
tableData = app.UITable.Data;

% Her araç için bilgileri tabloya yaz
% --- Tabloyu güncelle
% --- Tabloyu güncelle
for i = 1:height(finalAssignments)
    vehIdx  = finalAssignments(i, 1);
    type    = finalAssignments(i, 2);
    station = finalAssignments(i, 3);
    socket  = finalAssignments(i, 4);

    % İstasyon formatı: AC/DC + no,socket
    if type == 1
        stationStr = sprintf('AC %d,%d', station, socket);
    else
        stationStr = sprintf('DC %d,%d', station, socket);
    end

    % Hücre dizisinden zaman bilgilerini çek (Sıra: Finish - Start)
    finishTime = vehicleResults{vehIdx, 5};  % FinishTime sütunu
    startTime  = vehicleResults{vehIdx, 6};  % StartTime sütunu
    chargeTime = finishTime - startTime;

    % Tabloya yaz
    tableData{vehIdx, 4} = stationStr;
    tableData{vehIdx, 5} = round(startTime / 60);      % Başlangıç zamanı (dk)
    tableData{vehIdx, 6} = round(chargeTime / 60);     % Şarj süresi (dk)
    tableData{vehIdx, 7} = round(finishTime / 60);     % Bitiş zamanı (dk)
end

% --- Arayüzde tabloyu güncelle
app.UITable.Data = tableData;

% --- Toplam süreyi EditField'a yaz
finishTimes = cell2mat(vehicleResults(:, 5));  % FinishTime sütunu (5. sütun)
totalTime = max(finishTimes);
app.TotalTimeEditField.Value = round(totalTime / 60);  % Dakika olarak


        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
        selectedCity = app.CityDropDown.Value;

    temperature = getWeather(app, selectedCity);  % 🔥 BURASI EN KRİTİK KISIM

    if ~isnan(temperature)
        app.TemperatureEditField.Value = round(temperature, 1);
    else
        app.TemperatureEditField.Value = NaN;
        uialert(app.UIFigure, 'Hava durumu alınamadı.', 'Hata', 'Icon', 'warning');
    end
            
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
% =====================
% KULLANICI GİRİŞLERİ
% =====================
n     = app.NumberofVehiclesEditField_2.Value;       % Araç sayısı
C     = app.BatteryCapacityEditField_5.Value;        % Batarya kapasitesi (kWh)
t     = app.TimeEditField.Value;                     % Süre (saat)

% =====================
% KAPSAMA KATSAYILARI (% BATARYA)
% =====================
k50 = 0.50 * C;       % %50 kapsama → batarya kapasitesinin %50'si
k80 = 0.5833 * C;     % %80 kapsama → batarya kapasitesinin %58,33'ü

% =====================
% GEREKEN ENERJİLER (kWh)
% =====================
E1 = k50 * n;         % %50 kapsama eşiği
E2 = k80 * n;         % %80 kapsama eşiği

% =====================
% GEREKEN GÜÇ (kW)
% =====================
Ptotal1 = E1 / t;
Ptotal2 = E2 / t;

% =====================
% AC/DC Güç, Maliyet ve Model Listeleri
% =====================
ac_list  = [22, 11, 7.4];
ac_cost  = [32500, 25000, 20000];
ac_name  = {'Vestel EVC04 22kW', 'Schneider EVH4S11NC 11kW', 'Piller 7.4kW'};

dc_list  = [60, 90, 120];
dc_cost  = [1000000, 1200000, 1500000];
dc_name  = {'TommaTech 60kW', 'Tunçmatik Pro 90kW', 'Star Charge 120kW'};

% =====================
% OPTION 1: %50 kapsama
% =====================
results1 = {};

% AC
for i = 1:length(ac_list)
    Quantity = ceil(Ptotal1 / ac_list(i));
    cost = Quantity * ac_cost(i);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results1(end+1,:) = {'AC', ac_name{i}, Quantity, cost_str};
end

% DC
for i = 1:length(dc_list)
    Quantity = ceil(Ptotal1 / dc_list(i));
    cost = Quantity * dc_cost(i);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results1(end+1,:) = {'DC', dc_name{i}, Quantity, cost_str};
end

% AC+DC
for i = 1:min(length(ac_list), length(dc_list))
    toplam_guc = ac_list(i) + dc_list(i);
    Quantity = ceil(Ptotal1 / toplam_guc);
    cost = Quantity * (ac_cost(i) + dc_cost(i));
    model_str = sprintf('%s (%d Quantity) + %s (%d Quantity)', ...
                        ac_name{i}, Quantity, dc_name{i}, Quantity);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results1(end+1,:) = {'AC+DC', model_str, Quantity, cost_str};
end

% Maliyete göre sırala
costs_num1 = cellfun(@(x) str2double(strrep(x, '.', '')), results1(:,4));
[~, idx1] = sort(costs_num1, 'ascend');
results1 = results1(idx1,:);

% =====================
% OPTION 2: %80 kapsama
% =====================
results2 = {};

% AC
for i = 1:length(ac_list)
    Quantity = ceil(Ptotal2 / ac_list(i));
    cost = Quantity * ac_cost(i);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results2(end+1,:) = {'AC', ac_name{i}, Quantity, cost_str};
end

% DC
for i = 1:length(dc_list)
    Quantity = ceil(Ptotal2 / dc_list(i));
    cost = Quantity * dc_cost(i);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results2(end+1,:) = {'DC', dc_name{i}, Quantity, cost_str};
end

% AC+DC
for i = 1:min(length(ac_list), length(dc_list))
    toplam_guc = ac_list(i) + dc_list(i);
    Quantity = ceil(Ptotal2 / toplam_guc);
    cost = Quantity * (ac_cost(i) + dc_cost(i));
    model_str = sprintf('%s (%d Quantity) + %s (%d Quantity)', ...
                        ac_name{i}, Quantity, dc_name{i}, Quantity);
    cost_str = regexprep(num2str(cost, '%.0f'), '(?<=\d)(?=(\d{3})+$)', '.');
    results2(end+1,:) = {'AC+DC', model_str, Quantity, cost_str};
end

% Maliyete göre sırala
costs_num2 = cellfun(@(x) str2double(strrep(x, '.', '')), results2(:,4));
[~, idx2] = sort(costs_num2, 'ascend');
results2 = results2(idx2,:);

% =====================
% TABLOYA YAZDIR
% =====================
app.Option1Table.Data = results1;
app.Option2Table.Data = results2;
app.Option1Table.ColumnName = {'Type', 'Model', 'Quantity', 'Cost (TL)'};
app.Option2Table.ColumnName = {'Type', 'Model', 'Quantity', 'Cost (TL)'};

% =====================
% HISTOGRAM
% =====================
% =====================
% 20 ARAÇ REFERANS VERİLERİ
% =====================
% Buraya CSV'den aldığın Energy20 ve Freq20 verilerini koyacağız
Energy20 = [60.0,66.0,72.0,78.0,84.0,90.0,96.0,102.0,108.0,114.0,120.0,126.0,132.0,138.0,144.0,150.0,156.0,162.0,168.0,174.0,180.0,186.0,192.0,198.0,204.0,210.0,216.0,222.0,228.0,234.0];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
  % Eksik Enerji (kWh) listesi (20 araç, 60 kWh referansı)
Freq20   = [1,1,2,3,5,7,11,15,22,30,41,54,73,94,123,157,201,252,318,393,488,597,730,883,1069,1279,1530,1815,2149,2525];  % Frekans listesi (20 araç, 60 kWh referansı)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

g = 10;  % Grup sayısı (verinin üretildiği grup sayısı)

% =====================
% ENERGY EKSENİNİ ÖLÇEKLEME
% =====================
Energy_scaled = Energy20 * (C / 60) * (n / 20);

% =====================
% FREKANS EKSENİNİ ÖLÇEKLEME
% =====================
S20 = nchoosek(20 + g - 1, g - 1);  % 20 araç için toplam kombinasyon sayısı
Sn  = nchoosek(n + g - 1, g - 1);   % n araç için toplam kombinasyon sayısı
Freq_scaled = Freq20 * (Sn / S20);

% =====================
% OPTION1 ve OPTION2 (kWh bazında)
% =====================
opt1_val = 0.50 * C * n;      % %50 kapsama eşiği
opt2_val = 0.5833 * C * n;    % %80 kapsama eşiği

% =====================
% HISTOGRAM ÇİZİMİ
% =====================
cla(app.HistogramAxes);
bar(app.HistogramAxes, Energy_scaled, Freq_scaled, ...
    'FaceColor', [0.3 0.7 0.9], 'EdgeColor', 'k');
hold(app.HistogramAxes, 'on');

% Option1 ve Option2 dikey çizgiler
xline(app.HistogramAxes, opt1_val, '--r', ...
    sprintf('Option1: %.0f kWh', opt1_val), 'LineWidth', 2);
xline(app.HistogramAxes, opt2_val, '--g', ...
    sprintf('Option2: %.0f kWh', opt2_val), 'LineWidth', 2);

% Ekseni ayarlama
xlim(app.HistogramAxes, [min(Energy_scaled), max(Energy_scaled)]);
ylim(app.HistogramAxes, [0, max(Freq_scaled) * 1.1]);
ytickformat(app.HistogramAxes, '%d');  % Burayı ekle
xlabel(app.HistogramAxes, 'Total Missing Energy (kWh)');
ylabel(app.HistogramAxes, 'Frequency (Number of Scenarios)');
app.HistogramAxes.YAxis.Exponent = 0;
ytickformat(app.HistogramAxes, '%,d');  % Binlik ayraçlı tam sayı formatı
title(app.HistogramAxes, sprintf('%d Vehicles Missing Energy Histogram', n));

hold(app.HistogramAxes, 'off');


        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
% =====================
% KULLANICI GİRİŞLERİ
% =====================
C        = app.BatteryCapacityEditField_5.Value;        % Batarya kapasitesi (kWh)
t        = app.TimeEditField_2.Value;                   % Süre (saat)
ac_count = app.NumberofACStationEditField_2.Value;      % AC istasyon sayısı
dc_count = app.NumberofDCStationEditField_2.Value;      % DC istasyon sayısı
ac_power = app.ACStationPowerEditField_2.Value;         % AC istasyon gücü (kW)
dc_power = app.DCStationPowerEditField_2.Value;         % DC istasyon gücü (kW)

% =====================
% KAPSAMA KATSAYILARI (% BATARYA)
% =====================
k50 = 0.50 * C;       % %50 kapsama → batarya kapasitesinin %50'si
k80 = 0.5833 * C;     % %80 kapsama → batarya kapasitesinin %58,33'ü

% =====================
% TEK ARAÇ ENERJİ İHTİYACI (kWh)
% =====================
energy_per_vehicle1 = k50;   % Option1 = %50 kapsama
energy_per_vehicle2 = k80;   % Option2 = %80 kapsama

% =====================
% AC İÇİN ZAMAN BAZLI HESAP
% =====================
ac_charge_time1 = energy_per_vehicle1 / ac_power;  % saat
ac_charge_time2 = energy_per_vehicle2 / ac_power;  % saat

max_vehicles_ac_1 = floor((ac_count * t) / ac_charge_time1);
max_vehicles_ac_2 = floor((ac_count * t) / ac_charge_time2);

% =====================
% DC İÇİN ZAMAN BAZLI HESAP (2 soketli)
% =====================
dc_charge_time1 = energy_per_vehicle1 / dc_power;  % saat
dc_charge_time2 = energy_per_vehicle2 / dc_power;  % saat

max_vehicles_dc_1 = floor((dc_count * 2 * t) / dc_charge_time1);
max_vehicles_dc_2 = floor((dc_count * 2 * t) / dc_charge_time2);

% =====================
% HİBRİT SİSTEM (AC + DC)
% =====================
total_vehicles_1 = max_vehicles_ac_1 + max_vehicles_dc_1;
total_vehicles_2 = max_vehicles_ac_2 + max_vehicles_dc_2;

% =====================
% SONUÇLARI TABLOYA YAZDIR
% =====================
app.Option1table1.Data = {
    'AC Only', max_vehicles_ac_1;
    'DC Only', max_vehicles_dc_1;
    'AC + DC', total_vehicles_1
};
app.Option2table2.Data = {
    'AC Only', max_vehicles_ac_2;
    'DC Only', max_vehicles_dc_2;
    'AC + DC', total_vehicles_2
};

app.Option1table1.ColumnName = {'Configuration', 'Max. Number of Vehicles'};
app.Option2table2.ColumnName = {'Configuration', 'Max. Number of Vehicles'};


% =====================
% HISTOGRAM
% =====================
% =====================
% REFERANS VERİ (20 araç, 60 kWh)
% =====================
Energy20 = [60.0,66.0,72.0,78.0,84.0,90.0,96.0,102.0,108.0,114.0,120.0,126.0,132.0,138.0,144.0,150.0,156.0,162.0,168.0,174.0,180.0,186.0,192.0,198.0,204.0,210.0,216.0,222.0,228.0,234.0];  % Eksik Enerji (kWh) listesi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
Freq20   = [1,1,2,3,5,7,11,15,22,30,41,54,73,94,123,157,201,252,318,393,488,597,730,883,1069,1279,1530,1815,2149,2525];  % Frekans listesi (20 araç, 60 kWh referansı)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
  % Frekans listesi

g = 10;  % Bu veri 10 grup ile üretildi

S20 = nchoosek(20 + g - 1, g - 1);  % 20 araç toplam senaryo sayısı

% =====================
% OPTION1 Histogram (Axes4)
% =====================
n1 = total_vehicles_1;  % Option1 araç sayısı
S_n1 = nchoosek(n1 + g - 1, g - 1);

Energy_scaled1 = Energy20 * (C / 60) * (n1 / 20);
Freq_scaled1   = Freq20 * (S_n1 / S20);

opt1_val = 0.50 * C * n1;  % %50 kapsama eşiği (kWh)

cla(app.HistogramAxes4);
bar(app.HistogramAxes4, Energy_scaled1, Freq_scaled1, ...
    'FaceColor', [0.3 0.7 0.9], 'EdgeColor', 'k');
hold(app.HistogramAxes4, 'on');
xline(app.HistogramAxes4, opt1_val, '--r', ...
    sprintf('Option1: %.0f kWh', opt1_val), 'LineWidth', 2);

xlim(app.HistogramAxes4, [min(Energy_scaled1), max(Energy_scaled1)]);
ylim(app.HistogramAxes4, [0, max(Freq_scaled1) * 1.1]);
app.HistogramAxes4.YAxis.Exponent = 0;  
ytickformat(app.HistogramAxes4, '%,d');
xlabel(app.HistogramAxes4, 'Total Missing Energy (kWh)');
ylabel(app.HistogramAxes4, 'Frequency (Number of Scenarios)');
title(app.HistogramAxes4, sprintf('Option1 (%d Vehicle)', n1));
hold(app.HistogramAxes4, 'off');

% =====================
% OPTION2 Histogram (Axes5)
% =====================
n2 = total_vehicles_2;  % Option2 araç sayısı
S_n2 = nchoosek(n2 + g - 1, g - 1);

Energy_scaled2 = Energy20 * (C / 60) * (n2 / 20);
Freq_scaled2   = Freq20 * (S_n2 / S20);

opt2_val = 0.5833 * C * n2;  % %80 kapsama eşiği (kWh)

cla(app.HistogramAxes5);
bar(app.HistogramAxes5, Energy_scaled2, Freq_scaled2, ...
    'FaceColor', [0.3 0.7 0.9], 'EdgeColor', 'k');
hold(app.HistogramAxes5, 'on');
xline(app.HistogramAxes5, opt2_val, '--g', ...
    sprintf('Option2: %.0f kWh', opt2_val), 'LineWidth', 2);

xlim(app.HistogramAxes5, [min(Energy_scaled2), max(Energy_scaled2)]);
ylim(app.HistogramAxes5, [0, max(Freq_scaled2) * 1.1]);
app.HistogramAxes5.YAxis.Exponent = 0;  
ytickformat(app.HistogramAxes5, '%,d');
xlabel(app.HistogramAxes5, 'Total Missing Energy (kWh');
ylabel(app.HistogramAxes5, 'Frequency (Number of Scenarios)');
title(app.HistogramAxes5, sprintf('Option2 (%d Vehicle)', n2));
hold(app.HistogramAxes5, 'off');

        end

        % Button pushed function: StartButton_4
        function StartButton_4Pushed(app, event)
% ============================ KULLANICI GİRDİLERİ
numVehicles = app.NumberofVehiclesEditField_3.Value;
capacity    = app.BatteryCapacityEditField_6.Value;      % kWh

acCount     = app.NumberofACStationEditField_3.Value;
dcCount     = app.NumberofDCStationEditField_3.Value;
acPower     = app.ACStationPowerEditField_3.Value;
dcPower     = app.DCStationPowerEditField_3.Value;
sim_time    = 36000;
dt          = 1;

% ============================ TABLODAN ARAÇ VERİLERİ
data = app.UITable2.Data;
socList = zeros(numVehicles,1);
vipList = zeros(numVehicles,1);
for i = 1:numVehicles
    socList(i) = data{i,2} / 100;
    vipList(i) = data{i,3};
end

% ============================ ÖNCELİK HESABI
priorityScore = zeros(numVehicles,1);
for j = 1:numVehicles
    soc = socList(j);
    vip = vipList(j);
    if   vip == 2 && soc < 0.05
        category = 6;
    elseif soc < 0.05
        category = 5;
    elseif vip == 2
        category = 4;
    elseif vip == 1 && soc < 0.05
        category = 3;
    elseif vip == 1 
        category = 2;
    else 
        category = 1;
    end
    socInverted = round((1 - soc) * 100);
    priorityScore(j) = category * 100 + socInverted;
end
[~, sortedIndices] = sort(priorityScore, 'descend');

% ============================ ARAÇ YAPISI (QUEUE)
queue = [];
for k = 1:numVehicles
    i = sortedIndices(k);
    v.id = i;
    v.soc = socList(i);
    v.vip = vipList(i);
    v.battery = capacity;
   
    queue = [queue; v];
end

% ============================ SOKET & İSTASYON YAPISI
socketTemplate.occupied = false;
socketTemplate.vehicle = [];
socketTemplate.soc = 0;
socketTemplate.done = false;

dcStations = cell(1, dcCount);
acStations = cell(1, acCount);
for i = 1:dcCount
    dcStations{i}.id = i;
    dcStations{i}.sockets = {socketTemplate, socketTemplate};
end
for i = 1:acCount
    acStations{i}.id = i;
    acStations{i}.sockets = {socketTemplate};
end

% ============================ ARAÇ TAKİBİ (ZAMANLAR)
vehicleResults = struct();
for i = 1:numVehicles
    vehicleResults(i).startTime = [];
    vehicleResults(i).finishTime = [];
    vehicleResults(i).assignedStation = '-';
end

% ============================ ZAMAN DÖNGÜSÜ
for currentTime = 0:dt:sim_time

    % --- DC ATAMALARI
    for s = 1:length(dcStations)
        station = dcStations{s};
        for i = 1:2
            socket = station.sockets{i};
            if ~socket.occupied && ~isempty(queue)
                v = queue(1); queue(1) = [];
                socket.vehicle = v;
                socket.soc = v.soc;
                socket.occupied = true;
                socket.done = false;
                socket.startTime = currentTime;
                station.sockets{i} = socket;

                vehicleResults(v.id).startTime = currentTime;
                vehicleResults(v.id).assignedStation = sprintf("DC %d,%d", s, i);
            end
        end
        dcStations{s} = station;
    end

    % --- AC ATAMALARI
    for s = 1:length(acStations)
        station = acStations{s};
        for i = 1
            socket = station.sockets{i};
            if ~socket.occupied && ~isempty(queue)
                v = queue(1); queue(1) = [];
                socket.vehicle = v;
                socket.soc = v.soc;
                socket.occupied = true;
                socket.done = false;
                socket.startTime = currentTime;
                station.sockets{i} = socket;

                vehicleResults(v.id).startTime = currentTime;
                vehicleResults(v.id).assignedStation = sprintf("AC %d,%d", s, i);
            end
        end
        acStations{s} = station;
    end

    % --- DC GÜNCELLEME
    for s = 1:length(dcStations)
        station = dcStations{s};
        sockets = station.sockets;
        connected = find(~cellfun(@(x) x.done || ~x.occupied, sockets));
        if length(connected) == 2
            s1 = sockets{connected(1)};
            s2 = sockets{connected(2)};
            v1 = s1.vehicle;
            v2 = s2.vehicle;
            if v1.vip > v2.vip
                p1 = 0.7 * dcPower; p2 = 0.3 * dcPower;
            elseif v2.vip > v1.vip
                p1 = 0.3 * dcPower; p2 = 0.7 * dcPower;
            else
                p1 = 0.5 * dcPower; p2 = 0.5 * dcPower;
            end
            powers = [p1,p2];
            for idx = 1:2
                si = connected(idx);
                sock = sockets{si};
                v = sock.vehicle;
                delta = powers(idx)/(v.battery*3600);
                sock.soc = sock.soc + delta;
                if sock.soc >= 1
                    sock.soc = 1;
                    sock.done = true;
                    sock.occupied = false;
                    vehicleResults(v.id).finishTime = currentTime;
                end
                sockets{si} = sock;
            end
        elseif length(connected) == 1
            s1 = sockets{connected(1)};
            v1 = s1.vehicle;
            p = dcPower;
            delta = p/(v1.battery*3600);
            s1.soc = s1.soc + delta;
            if s1.soc >= 1
                s1.soc = 1;
                s1.done = true;
                s1.occupied = false;
                vehicleResults(v1.id).finishTime = currentTime;
            end
            sockets{connected(1)} = s1;
        end
        station.sockets = sockets;
        dcStations{s} = station;
    end

    % --- AC GÜNCELLEME
    for s = 1:length(acStations)
        station = acStations{s};
        for i = 1
            socket = station.sockets{i};
            if socket.occupied && ~socket.done
                v = socket.vehicle;
                p = acPower;
                delta = p / (v.battery * 3600);
                socket.soc = socket.soc + delta;
                if socket.soc >= 1
                    socket.soc = 1;
                    socket.done = true;
                    socket.occupied = false;
                    vehicleResults(v.id).finishTime = currentTime;
                end
                station.sockets{i} = socket;
            end
        end
        acStations{s} = station;
    end
end

% ============================ TABLOYA YAZ
tableData = app.UITable2.Data;
for i = 1:numVehicles
    v = vehicleResults(i);
    if ~isempty(v.finishTime)
        startMin  = round(v.startTime / 60, 1);
        finishMin = round(v.finishTime / 60, 1);
        chargeMin = round((v.finishTime - v.startTime) / 60, 1);

      tableData{i,4} = char(v.assignedStation);  % string → char dönüşümü             % Station (zaten string)
tableData{i, 5} = sprintf('%.1f', startMin);         % Start Time (string)
tableData{i, 6} = sprintf('%.1f', chargeMin);        % Charging Time (string)
tableData{i, 7} = sprintf('%.1f', finishMin);        % Finish Time (string)
    end
end
app.UITable2.Data = tableData;
% ================================
% Tüm araçların bitiş süresini al
% ================================
finishTimes = zeros(numVehicles, 1);
for i = 1:numVehicles
    if isfield(vehicleResults(i), 'finishTime') && ~isempty(vehicleResults(i).finishTime)
        finishTimes(i) = vehicleResults(i).finishTime;
    end
end

% Tüm araçlar şarj olmuşsa:
if all(finishTimes > 0)
    totalTimeSec = max(finishTimes);         % En son biten araç zamanı (saniye)
    totalTimeMin = round(totalTimeSec / 60); % Dakikaya çevir

    % Edit Field’a yaz
    app.TotalTimeEditField_2.Value = totalTimeMin;
end
% === LOG'LARI YAZDIR ===


disp("=========================================");
disp("          ARAÇ ATAMA LOG KAYITLARI       ");
disp("=========================================");

disp(" ");
disp("=========================================");
disp("            DC İSTASYON DURUMU           ");
disp("=========================================");
for s = 1:length(dcStations)
    fprintf(">> DC İstasyon %d:\n", dcStations{s}.id);
    for i = 1:length(dcStations{s}.sockets)
        sock = dcStations{s}.sockets{i};
        if sock.occupied
            fprintf("  Soket %d: Araç %d atanmış | SOC = %.2f\n", ...
                    i, sock.vehicle.id, sock.soc);
        else
            fprintf("  Soket %d: BOŞ\n", i);
        end
    end
end

disp(" ");
disp("=========================================");
disp("            AC İSTASYON DURUMU           ");
disp("=========================================");
for s = 1:length(acStations)
    fprintf(">> AC İstasyon %d:\n", acStations{s}.id);
    for i = 1:length(acStations{s}.sockets)
        sock = acStations{s}.sockets{i};
        if sock.occupied
            fprintf("  Soket %d: Araç %d atanmış | SOC = %.2f\n", ...
                    i, sock.vehicle.id, sock.soc);
        else
            fprintf("  Soket %d: BOŞ\n", i);
        end
    end
end

disp(" ");
disp("=========================================");
disp("        KUYRUKTA KALAN ARAÇLAR           ");
disp("=========================================");
for i = 1:length(queue)
    v = queue(i);
    fprintf("Araç %d | SOC: %.2f | VIP: %d\n", v.id, v.soc, v.vip);
end
% === Tüm Edit Field'ların yazı boyutunu büyüt ===
allEdits = [findall(app.UIFigure, 'Type', 'uieditfield'), ...
            findall(app.UIFigure, 'Type', 'uinumericeditfield')];
set(allEdits, 'FontSize', 16);

% === Tüm Label'ların yazı boyutunu büyüt ===
allLabels = findall(app.UIFigure, 'Type', 'uilabel');
set(allLabels, 'FontSize', 16);


        end

        % Button pushed function: GenerateTableButton_2
        function GenerateTableButton_2Pushed(app, event)
    % Araç sayısını al
    numVehicles = app.NumberofVehiclesEditField_3.Value;

    % Tablo başlıkları
    columnNames = {'Vehicles', 'SOC (%)', 'VIP Level', ...
                   'Assigned Station', 'Start Time (min)', ...
                   'Charging Time (min)', 'Finish Time (min)'};

    % Boş tabloyu oluştur
    tableData = cell(numVehicles, 7);
    for i = 1:numVehicles
        tableData{i, 1} = sprintf('Vehicle %d', i);  % Araç adı
        tableData{i, 2} = randi([1 100]);            % SOC (%), 1–100 arası
        tableData{i, 3} = 0;                         % VIP Level (kullanıcı girer)
        tableData{i, 4} = '-';                       % Assigned Station
        tableData{i, 5} = '-';                       % Start Time
        tableData{i, 6} = '-';                       % Charging Time
        tableData{i, 7} = '-';                       % Finish Time
    end

    % Tabloya yaz
    app.UITable2.Data = tableData;
    app.UITable2.ColumnName = columnNames;
    app.UITable2.ColumnEditable = [false true true false false false false];

    app.UITable2.FontSize = 20;
        end

        % Value changed function: ClassDropDown
        function ClassDropDownValueChanged(app, event)
           selectedClass = app.ClassDropDown.Value;

    switch selectedClass
        case 'Forklift'
            app.BatteryCapacityEditField_4.Value = 24;   % kWh 
            
           app.BatteryCapacityEditField_4.Editable = false;
           

        case 'Passenger Car'
            app.BatteryCapacityEditField_4.Value = 60;   % kWh
           
            app.BatteryCapacityEditField_4.Editable = false;
            

        case 'Commercial Vehicle'
            app.BatteryCapacityEditField_4.Value = 80;   % kWh
            
            app.BatteryCapacityEditField_4.Editable = false;
            
        case 'Custom'
            % Kullanıcı kendi değerini girebilir
            app.BatteryCapacityEditField_4.Enable = 'on';
            
    end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.SizeChangedFcn = createCallbackFcn(app, @VehicleModelListDropDownValueChanged, true);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Vehicle1Label = uilabel(app.Tab);
            app.Vehicle1Label.Text = 'Vehicle1';
            app.Vehicle2Label = uilabel(app.Tab);
            app.Vehicle2Label.Text = 'Vehicle2';
            app.START = uibutton(app.Tab, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.START.Text = 'START';
            app.SOCEditField = uieditfield(app.Tab, 'numeric');
            app.SOCEditField.Value = 0.3;
            app.SOCEditFieldLabel = uilabel(app.Tab);
            app.SOCEditFieldLabel.Text = 'SOC';
            app.BatteryCapacityLabel_2 = uilabel(app.Tab);
            app.BatteryCapacityLabel_2.Text = 'BatteryCapacity';
            app.BatteryCapacityEditField = uieditfield(app.Tab, 'numeric');
            app.BatteryCapacityEditField.Value = 64;
            app.VIPEditFieldLabel = uilabel(app.Tab);
            app.VIPEditFieldLabel.Text = 'VIP';
            app.VIPEditField = uieditfield(app.Tab, 'numeric');
            app.SOCEditField_2 = uieditfield(app.Tab, 'numeric');
            app.SOCEditField_2.Value = 0.35;
            app.SOCEditField_2Label = uilabel(app.Tab);
            app.SOCEditField_2Label.Text = 'SOC';
            app.BatteryCapacityLabel = uilabel(app.Tab);
            app.BatteryCapacityLabel.Text = 'BatteryCapacity';
            app.BatteryCapacityEditField_2 = uieditfield(app.Tab, 'numeric');
            app.BatteryCapacityEditField_2.Value = 62;
            app.VIPEditField_2Label = uilabel(app.Tab);
            app.VIPEditField_2Label.Text = 'VIP';
            app.VIPEditField_2 = uieditfield(app.Tab, 'numeric');
            app.StationPowerEditFieldLabel = uilabel(app.Tab);
            app.StationPowerEditFieldLabel.Text = 'Station Power';
            app.StationPowerEditField = uieditfield(app.Tab, 'numeric');
            app.StationPowerEditField.Value = 100;
            app.ModeButtonGroup = uibuttongroup(app.Tab);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.ACButton = uiradiobutton(app.ModeButtonGroup);
            app.ACButton.Text = 'AC';
            app.ACButton.Value = true;
            app.DCButton = uiradiobutton(app.ModeButtonGroup);
            app.DCButton.Text = 'DC';
            app.VehicleModelListLabel = uilabel(app.Tab);
            app.VehicleModelListLabel.Text = 'Vehicle Model List';
            app.VehicleModelListDropDown = uidropdown(app.Tab);
            app.VehicleModelListDropDown.Items = {'Custom', 'Tesla Model 3', 'Renault Zoe', 'Hyundai Ioniq 5'};
            app.VehicleModelListDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VehicleModelListDropDownValueChanged, true);
            app.VehicleModelListDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleModelListDropDownValueChanged, true);
            app.VehicleModelListDropDown.Value = 'Custom';
            app.VehicleModelListDropDown_2Label = uilabel(app.Tab);
            app.VehicleModelListDropDown_2Label.Text = 'Vehicle Model List';
            app.VehicleModelListDropDown_2 = uidropdown(app.Tab);
            app.VehicleModelListDropDown_2.Items = {'Custom', 'Tesla Model 3', 'Renault Zoe', 'Hyundai Ioniq 5'};
            app.VehicleModelListDropDown_2.ValueChangedFcn = createCallbackFcn(app, @VehicleModelListDropDown_2ValueChanged, true);
            app.VehicleModelListDropDown_2.Value = 'Custom';
            app.CityDropDownLabel = uilabel(app.Tab);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.Tab);
            app.CityDropDown.Items = {'İstanbul', 'Ankara', 'İzmir', 'Adana', 'Adıyaman', 'Afyonkarahisar', 'Ağrı', 'Aksaray', 'Amasya', 'Antalya', 'Ardahan', 'Artvin', 'Aydın', 'Balıkesir', 'Bartın', 'Batman', 'Bayburt', 'Bilecik', 'Bingöl', 'Bitlis', 'Bolu', 'Burdur', 'Bursa', 'Çanakkale', 'Çankırı', 'Çorum', 'Denizli', 'Diyarbakır', 'Düzce', 'Edirne', 'Elazığ', 'Erzincan', 'Erzurum', 'Eskişehir', 'Gaziantep', 'Giresun', 'Gümüşhane', 'Hakkâri', 'Hatay', 'Iğdır', 'Isparta', 'Kahramanmaraş', 'Karabük', 'Karaman', 'Kars', 'Kastamonu', 'Kayseri', 'Kırıkkale', 'Kırklareli', 'Kırşehir', 'Kilis', 'Kocaeli', 'Konya', 'Kütahya', 'Malatya', 'Manisa', 'Mardin', 'Mersin', 'Muğla', 'Muş', 'Nevşehir', 'Niğde', 'Ordu', 'Osmaniye', 'Rize', 'Sakarya', 'Samsun', 'Siirt', 'Sinop', 'Sivas', 'Şanlıurfa', 'Şırnak', 'Tekirdağ', 'Tokat', 'Trabzon', 'Tunceli', 'Uşak', 'Van', 'Yalova', 'Yozgat', 'Zonguldak'};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = 'İstanbul';
            app.TemperatureEditFieldLabel = uilabel(app.Tab);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.NumberofDCStationEditFieldLabel = uilabel(app.Tab2);
            app.NumberofDCStationEditFieldLabel.Text = 'Number of DC Station';
            app.NumberofDCStationEditField = uieditfield(app.Tab2, 'numeric');
            app.NumberofDCStationEditField.Value = 2;
            app.NumberofACStationEditFieldLabel = uilabel(app.Tab2);
            app.NumberofACStationEditFieldLabel.Text = 'Number of AC Station';
            app.NumberofACStationEditField = uieditfield(app.Tab2, 'numeric');
            app.NumberofACStationEditField.Value = 1;
            app.NumberofVehiclesEditFieldLabel = uilabel(app.Tab2);
            app.NumberofVehiclesEditFieldLabel.Text = 'Number of Vehicles';
            app.NumberofVehiclesEditField = uieditfield(app.Tab2, 'numeric');
            app.NumberofVehiclesEditField.Value = 7;
            app.StartButton = uibutton(app.Tab2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.BatteryCapacityEditField_3Label = uilabel(app.Tab2);
            app.BatteryCapacityEditField_3Label.Text = 'Battery Capacity';
            app.BatteryCapacityEditField_3 = uieditfield(app.Tab2, 'numeric');
            app.BatteryCapacityEditField_3.Value = 60;
            app.MaxChargingPowerEditFieldLabel = uilabel(app.Tab2);
            app.MaxChargingPowerEditFieldLabel.Text = 'Max Charging Power';
            app.MaxChargingPowerEditField = uieditfield(app.Tab2, 'numeric');
            app.MaxChargingPowerEditField.Value = 50;
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.GenerateTableButton = uibutton(app.Tab2, 'push');
            app.GenerateTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTableButtonPushed, true);
            app.GenerateTableButton.Text = 'GenerateTable';
            app.DCStationPowerLabel = uilabel(app.Tab2);
            app.DCStationPowerLabel.Text = 'DC Station Power';
            app.DCStationPowerEditField = uieditfield(app.Tab2, 'numeric');
            app.DCStationPowerEditField.Value = 100;
            app.ACStationPowerEditFieldLabel = uilabel(app.Tab2);
            app.ACStationPowerEditFieldLabel.Text = 'AC Station Power';
            app.ACStationPowerEditField = uieditfield(app.Tab2, 'numeric');
            app.ACStationPowerEditField.Value = 22;
            app.TotalTimeEditFieldLabel = uilabel(app.Tab2);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.Tab2, 'numeric');
            app.TotalTimeEditField.Editable = 'off';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.HistogramAxes = uiaxes(app.Tab3);
            title(app.HistogramAxes, 'Title')
            xlabel(app.HistogramAxes, 'X')
            ylabel(app.HistogramAxes, 'Y')
            zlabel(app.HistogramAxes, 'Z')
            app.NumberofVehiclesLabel = uilabel(app.Tab3);
            app.NumberofVehiclesLabel.Text = 'Number of Vehicles';
            app.NumberofVehiclesEditField_2 = uieditfield(app.Tab3, 'numeric');
            app.NumberofVehiclesEditField_2.Value = 20;
            app.ClassDropDownLabel = uilabel(app.Tab3);
            app.ClassDropDownLabel.Text = 'Class';
            app.ClassDropDown = uidropdown(app.Tab3);
            app.ClassDropDown.Items = {'Forklift', 'Passenger Car', 'Commercial Vehicle', 'Custom'};
            app.ClassDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassDropDownValueChanged, true);
            app.ClassDropDown.Value = 'Custom';
            app.BatteryCapacityLabel_3 = uilabel(app.Tab3);
            app.BatteryCapacityLabel_3.Text = 'Battery Capacity';
            app.BatteryCapacityEditField_4 = uieditfield(app.Tab3, 'numeric');
            app.BatteryCapacityEditField_4.Value = 60;
            app.TimeEditFieldLabel = uilabel(app.Tab3);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.Tab3, 'numeric');
            app.TimeEditField.Value = 4;
            app.StartButton_2 = uibutton(app.Tab3, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Option1Table = uitable(app.Tab3);
            app.Option1Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Option1Table.RowName = {};
            app.Option2Table = uitable(app.Tab3);
            app.Option2Table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Option2Table.RowName = {};
            app.Option1Tablefor50ofallscenariosLabel = uilabel(app.Tab3);
            app.Option1Tablefor50ofallscenariosLabel.Text = 'Option 1: Table for 50% of all scenarios';
            app.Option2Tablefor80ofallscenariosLabel = uilabel(app.Tab3);
            app.Option2Tablefor80ofallscenariosLabel.Text = 'Option 2: Table for 80% of all scenarios';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.HistogramAxes4 = uiaxes(app.Tab4);
            title(app.HistogramAxes4, 'Title')
            xlabel(app.HistogramAxes4, 'X')
            ylabel(app.HistogramAxes4, 'Y')
            zlabel(app.HistogramAxes4, 'Z')
            app.HistogramAxes5 = uiaxes(app.Tab4);
            title(app.HistogramAxes5, 'Title')
            xlabel(app.HistogramAxes5, 'X')
            ylabel(app.HistogramAxes5, 'Y')
            zlabel(app.HistogramAxes5, 'Z')
            app.StartButton_3 = uibutton(app.Tab4, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.ACStationPowerLabel = uilabel(app.Tab4);
            app.ACStationPowerLabel.Text = 'AC Station Power';
            app.ACStationPowerEditField_2 = uieditfield(app.Tab4, 'numeric');
            app.ACStationPowerEditField_2.Value = 22;
            app.DCStationPowerEditField_2Label = uilabel(app.Tab4);
            app.DCStationPowerEditField_2Label.Text = 'DC Station Power';
            app.DCStationPowerEditField_2 = uieditfield(app.Tab4, 'numeric');
            app.DCStationPowerEditField_2.Value = 100;
            app.TimeEditField_2Label = uilabel(app.Tab4);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.Tab4, 'numeric');
            app.TimeEditField_2.Value = 4;
            app.BatteryCapacityEditField_5Label = uilabel(app.Tab4);
            app.BatteryCapacityEditField_5Label.Text = 'Battery Capacity';
            app.BatteryCapacityEditField_5 = uieditfield(app.Tab4, 'numeric');
            app.BatteryCapacityEditField_5.Value = 60;
            app.NumberofACStationEditField_2Label = uilabel(app.Tab4);
            app.NumberofACStationEditField_2Label.Text = 'Number of AC Station';
            app.NumberofACStationEditField_2 = uieditfield(app.Tab4, 'numeric');
            app.NumberofACStationEditField_2.Value = 4;
            app.NumberofDCStationEditField_2Label = uilabel(app.Tab4);
            app.NumberofDCStationEditField_2Label.Text = 'Number of DC Station';
            app.NumberofDCStationEditField_2 = uieditfield(app.Tab4, 'numeric');
            app.NumberofDCStationEditField_2.Value = 2;
            app.Option1table1 = uitable(app.Tab4);
            app.Option1table1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Option1table1.RowName = {};
            app.Option2table2 = uitable(app.Tab4);
            app.Option2table2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Option2table2.RowName = {};
            app.Option1Tablefor50ofallscenariosLabel_2 = uilabel(app.Tab4);
            app.Option1Tablefor50ofallscenariosLabel_2.Text = 'Option 1: Table for 50% of all scenarios';
            app.Option2Tablefor80ofallscenariosLabel_2 = uilabel(app.Tab4);
            app.Option2Tablefor80ofallscenariosLabel_2.Text = 'Option 2: Table for 80% of all scenarios';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.TotalTimeLabel = uilabel(app.Tab5);
            app.TotalTimeLabel.Text = 'Total Time';
            app.TotalTimeEditField_2 = uieditfield(app.Tab5, 'numeric');
            app.NumberofACStationLabel = uilabel(app.Tab5);
            app.NumberofACStationLabel.Text = 'Number of AC Station';
            app.NumberofACStationEditField_3 = uieditfield(app.Tab5, 'numeric');
            app.NumberofACStationEditField_3.Value = 3;
            app.NumberofDCStationLabel = uilabel(app.Tab5);
            app.NumberofDCStationLabel.Text = 'Number of DC Station';
            app.NumberofDCStationEditField_3 = uieditfield(app.Tab5, 'numeric');
            app.NumberofDCStationEditField_3.Value = 1;
            app.ACStationPowerLabel_2 = uilabel(app.Tab5);
            app.ACStationPowerLabel_2.Text = 'AC Station Power';
            app.ACStationPowerEditField_3 = uieditfield(app.Tab5, 'numeric');
            app.ACStationPowerEditField_3.Value = 22;
            app.StartButton_4 = uibutton(app.Tab5, 'push');
            app.StartButton_4.ButtonPushedFcn = createCallbackFcn(app, @StartButton_4Pushed, true);
            app.StartButton_4.Text = 'Start';
            app.GenerateTableButton_2 = uibutton(app.Tab5, 'push');
            app.GenerateTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateTableButton_2Pushed, true);
            app.GenerateTableButton_2.Text = 'GenerateTable';
            app.DCStationPowerLabel_2 = uilabel(app.Tab5);
            app.DCStationPowerLabel_2.Text = 'DC Station Power';
            app.DCStationPowerEditField_3 = uieditfield(app.Tab5, 'numeric');
            app.DCStationPowerEditField_3.Value = 100;
            app.UITable2 = uitable(app.Tab5);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.BatteryCapacityLabel_4 = uilabel(app.Tab5);
            app.BatteryCapacityLabel_4.Text = 'Battery Capacity';
            app.BatteryCapacityEditField_6 = uieditfield(app.Tab5, 'numeric');
            app.BatteryCapacityEditField_6.Value = 60;
            app.NumberofVehiclesLabel_2 = uilabel(app.Tab5);
            app.NumberofVehiclesLabel_2.Text = 'Number of Vehicles';
            app.NumberofVehiclesEditField_3 = uieditfield(app.Tab5, 'numeric');
            app.NumberofVehiclesEditField_3.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end